classdef FarField_r0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FarFieldDiffractionModelLabel  matlab.ui.control.Label
        ApertureTypeDropDownLabel      matlab.ui.control.Label
        ApertureTypeDropDown           matlab.ui.control.DropDown
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        SideLengthEditFieldLabel       matlab.ui.control.Label
        SideLengthEditField            matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        FarField                       matlab.ui.control.UIAxes
        CrossSection                   matlab.ui.control.UIAxes
        Aperture                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ApertureTypeDropDown
        function ApertureTypeDropDownValueChanged(app, event)
            switch app.ApertureTypeDropDown.Value
                case 'Circle'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Radius'; 
                case 'Square'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Height/Width';
                    app.SideLengthEditFieldLabel.Text = 'N/A';
                case 'Rectangle'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Height';
                    app.SideLengthEditFieldLabel.Text = 'Width';
                case 'Right Triangle'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Height';
                    app.SideLengthEditFieldLabel.Text = 'Width';
                case 'Single Slit'
                    app.SideLengthEditFieldLabel.Visible = 'off';
                    app.SideLengthEditField.Visible = 'off';
                    app.RadiusEditFieldLabel.Text = 'Slit Height';
                    app.SideLengthEditFieldLabel.Text = 'N/A';
                case 'Double Slit'
                    app.SideLengthEditFieldLabel.Visible = 'on';
                    app.SideLengthEditField.Visible = 'on';
                    app.RadiusEditFieldLabel.Text = 'Slit Height';
                    app.SideLengthEditFieldLabel.Text = 'Slit Spacing';
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Define Aperture field
            apl = 5000; % Size of the aperture field
            ap = zeros(apl); % Define actual aperture plane
            
            % Grab Values From The GUI
            apshape = app.ApertureTypeDropDown.Value;
            len1 = app.RadiusEditField.Value;
            len2 = app.SideLengthEditField.Value;
            
            % Generate The Aperture
            if strcmp(apshape,'Circle')
                apname = 'Circular'; % Define the name of the aperure using for the title
                % for every x, for every y
                for ijk=1:apl
                    for lmn=1:apl
                        % if the the point ap(ijk,lmn) is within the radius of the 
                        % circle from the center of the aperture, 
                        % the ap value at that point changes from 0 to 1
                        if round((1+apl/2-ijk)^2+(1+apl/2-lmn)^2) <= len1^2
                          ap(ijk,lmn)=1;
                        end
                    end
                end
            
            elseif strcmp(apshape,'Square')
                apname = 'Square'; % Define the name of the aperure using for the title
                % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                % +- len1/2 lengthwise about the center of the aperture
                ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-len1/2):round(1+apl/2+len1/2))=1;    
                
            elseif strcmp(apshape,'Rectangle')
                apname = 'Rectangular'; % Define the name of the aperure using for the title
                % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                % +- len2/2 lengthwise about the center of the aperture
                ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-len2/2):round(1+apl/2+len2/2))=1;
                
            elseif strcmp(apshape,'Right Triangle')
                apname = 'Triangular'; % Define the name of the aperure using for the title
                m = 0; % multiplier for step length
                dis = len2/len1; % increase in width for one increase in height
                for ijk=round(1+apl/2-len1/2):round(1+apl/2+len1/2)
                    % m*dis is step size, increases as ijk increases
                    for lmn=round(1+apl/2-len2/2):round(1+apl/2-len2/2+m*dis)
                        ap(ijk,lmn)=1;
                    end
                    m = m+1;
                end
                
            elseif strcmp(apshape,'Single Slit')
                apname = 'Single-Slit'; % Define the name of the aperure using for the title
                slitw = 200; % Define the slit-width
                % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                % +- 100 lengthwise about the center of the aperture
                ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-slitw/2):round(1+apl/2+slitw/2))=1;
                
            elseif strcmp(apshape,'Double Slit')
                apname = 'Double-Slit'; % Define the name of the aperure using for the title
                slitw = 175; % Define the slit-width
                % changes ap value from 0 to 1 in range of +- len1/2 heightwise and
                % +- 175/2 lengthwise about the points center +- len2/2 lengthwise
                ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-slitw/2-len2/2):round(1+apl/2+slitw/2-len2/2))=1;
                ap(round(1+apl/2-len1/2):round(1+apl/2+len1/2),round(1+apl/2-slitw/2+len2/2):round(1+apl/2+slitw/2+len2/2))=1;
                
            end
            
            % Plot the Aperture
            imagesc(app.Aperture,ap); % Plot image of the aperture field
            title(app.Aperture,[apname,' Aperture']); % Set the title for each plot
            app.Aperture.Colormap = gray;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FarFieldDiffractionModelLabel = uilabel(app.UIFigure);
            app.FarFieldDiffractionModelLabel.Text = 'Far-Field Diffraction Model';
            app.ApertureTypeDropDownLabel = uilabel(app.UIFigure);
            app.ApertureTypeDropDownLabel.Text = 'Aperture Type';
            app.ApertureTypeDropDown = uidropdown(app.UIFigure);
            app.ApertureTypeDropDown.Items = {'Circle', 'Square', 'Rectangle', 'Right Triangle', 'Single Slit', 'Double Slit'};
            app.ApertureTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ApertureTypeDropDownValueChanged, true);
            app.ApertureTypeDropDown.Value = 'Circle';
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SideLengthEditFieldLabel.Visible = 'off';
            app.SideLengthEditFieldLabel.Text = 'Side Length';
            app.SideLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SideLengthEditField.Visible = 'off';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FarField = uiaxes(app.UIFigure);
            title(app.FarField, 'Far Field Diffraction')
            xlabel(app.FarField, 'X')
            ylabel(app.FarField, 'Y')
            zlabel(app.FarField, 'Z')
            app.CrossSection = uiaxes(app.UIFigure);
            title(app.CrossSection, 'Cross Section of Y-axis')
            xlabel(app.CrossSection, 'X')
            ylabel(app.CrossSection, 'Y')
            zlabel(app.CrossSection, 'Z')
            app.Aperture = uiaxes(app.UIFigure);
            title(app.Aperture, 'Aperture')
            xlabel(app.Aperture, 'x [arb. units]')
            ylabel(app.Aperture, 'y [arb. units]')
            app.Aperture.XLim = [0 5000];
            app.Aperture.YLim = [0 5000];
            app.Aperture.YDir = 'reverse';
            app.Aperture.XTickLabel = {'0'; '5000'};
            app.Aperture.YTick = [0 5000];
            app.Aperture.YTickLabel = {'5000'; '0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FarField_r0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end